classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PengolahanCitraDigitalE081Label  matlab.ui.control.Label
        EndinRahmanda20081010070Label  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        ButtonNeg                      matlab.ui.control.Button
        ButtonHisto                    matlab.ui.control.Button
        ButtonGrey                     matlab.ui.control.Button
        Threshold                      matlab.ui.control.UIAxes
        Negative                       matlab.ui.control.UIAxes
        Histo                          matlab.ui.control.UIAxes
        Greyscaled                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        picture = rgb2gray(imread('EXTRA_LARGE.jpg'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonGrey
        function ButtonGreyPushed(app, event)
            imshow(app.picture, 'Parent', app.Greyscaled);
        end

        % Button pushed function: ButtonHisto
        function ButtonHistoPushed(app, event)
            hg = zeros(256, 1);
            [rows, cols] = size(app.picture);
            
            for i = 1:rows
                for j = 1:cols
                    pixel_value = app.picture(i, j) + 1;
                    hg(pixel_value) = hg(pixel_value) + 1;
                end
            end
            bar('Parent', app.Histo, 0:255, hg);
            xlabel('Pixel Value');
            ylabel('Frequency');
        end

        % Button pushed function: ButtonNeg
        function ButtonNegPushed(app, event)
            [rows, cols, channels] = size(app.picture);
            inverted_img = zeros(rows, cols, channels);
            
            for i = 1:rows
                for j = 1:cols
                    for k = 1:channels
                        inverted_img(i, j, k) = 255 - app.picture(i, j, k);
                    end
                end
            end
            imshow(uint8(inverted_img),'Parent', app.Negative);
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            value = app.Slider.Value;
            thres = app.picture>value;
            imshow(thres, 'Parent', app.Threshold);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Greyscaled = uiaxes(app.UIFigure);
            title(app.Greyscaled, 'Greyscaled')
            app.Greyscaled.XTick = [];
            app.Greyscaled.YTick = [];
            app.Histo = uiaxes(app.UIFigure);
            title(app.Histo, 'Histogram')
            app.Histo.XTick = [];
            app.Histo.YTick = [];
            app.Negative = uiaxes(app.UIFigure);
            title(app.Negative, 'Negative')
            app.Negative.XTick = [];
            app.Negative.YTick = [];
            app.Threshold = uiaxes(app.UIFigure);
            title(app.Threshold, 'Threshold')
            app.Threshold.XTick = [];
            app.Threshold.YTick = [];
            app.ButtonGrey = uibutton(app.UIFigure, 'push');
            app.ButtonGrey.ButtonPushedFcn = createCallbackFcn(app, @ButtonGreyPushed, true);
            app.ButtonGrey.Text = 'Show';
            app.ButtonHisto = uibutton(app.UIFigure, 'push');
            app.ButtonHisto.ButtonPushedFcn = createCallbackFcn(app, @ButtonHistoPushed, true);
            app.ButtonHisto.Text = 'Show';
            app.ButtonNeg = uibutton(app.UIFigure, 'push');
            app.ButtonNeg.ButtonPushedFcn = createCallbackFcn(app, @ButtonNegPushed, true);
            app.ButtonNeg.Text = 'Show';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.EndinRahmanda20081010070Label = uilabel(app.UIFigure);
            app.EndinRahmanda20081010070Label.Text = 'Endin Rahmanda - 20081010070';
            app.PengolahanCitraDigitalE081Label = uilabel(app.UIFigure);
            app.PengolahanCitraDigitalE081Label.Text = 'Pengolahan Citra Digital - E081';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end